classdef outsider_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Camera1Button         matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        Camera3Button         matlab.ui.control.Button
        Camera2Button         matlab.ui.control.Button
        Panel_2               matlab.ui.container.Panel
        DETECTIONSYSTEMLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       alarm % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Camera1Button
        function Camera1ButtonPushed(app, event)

if ~exist('webcam', 'file')
    msgbox('Error: Webcam not detected.', 'Error', 'error');
    return;
end

Webcam = webcam;
load net;
video_Player = vision.VideoPlayer();

face_Detector = vision.CascadeObjectDetector();
point_Tracker = vision.PointTracker('MaxBidirectionalError', 2);
is_tracking = false; % Initialize tracking
run_loop = true;
frame_Count = 0;

while isvalid(video_Player) && run_loop && frame_Count < 4000000
    video_Frame = snapshot(Webcam);
    gray_Frame = rgb2gray(video_Frame);
    frame_Count = frame_Count + 1;

    % Face detection
    bboxes = step(face_Detector, video_Frame);
    if ~isempty(bboxes)
        % Track the face if detected
        if ~is_tracking
            points = detectMinEigenFeatures(gray_Frame, 'ROI', bboxes(1, :));
            xy_Points = points.Location;
            number_of_Points = size(xy_Points, 1);

            if number_of_Points >= 10
                release(point_Tracker); % Release before re-initializing
                initialize(point_Tracker, xy_Points, gray_Frame);
                is_tracking = true;
                old_Points = xy_Points;
            end
        else
            [xy_Points, isFound] = step(point_Tracker, gray_Frame);
            new_Points = xy_Points(isFound, :);
            old_Points = old_Points(isFound, :);

            number_of_Points = size(new_Points, 1);

            if number_of_Points >= 10
                [xform, old_Points, new_Points] = estimateGeometricTransform(old_Points, new_Points, 'similarity', 'MaxDistance', 4);
                rectangle = bbox2points(bboxes(1, :));
                face_Polygon = reshape(rectangle', 1, []);
                video_Frame = insertShape(video_Frame, 'Polygon', face_Polygon, 'LineWidth', 3);
                video_Frame = insertMarker(video_Frame, new_Points, "+", 'Color', 'white');
                setPoints(point_Tracker, old_Points);
            else
                is_tracking = false;
            end
        end

        % Crop the face region and classify
        es = imcrop(video_Frame, bboxes(1, :));
        es = imresize(es, [227 227]);
        [label, scores] = classify(net, es);
        confidence = max(scores); % Maximum confidence score
        if confidence >= 0.7
            title_label = ['Tracking: ', char(label), ' (Confidence: ', num2str(confidence), ')'];
        else
            title_label = 'Outsider';
            msg = 'Warning: Outsider Detected!';
            showWarningMessageBox(msg);
        end
    else
        % Stop tracking if no face detected
        is_tracking = false;
        title_label = 'No Face Detected';
    end

    % Display the video frame with title
    imshow(video_Frame, 'Parent', app.UIAxes);
    title(app.UIAxes, title_label);
    drawnow;

    run_loop = isvalid(video_Player) && isvalid(app.UIFigure) && ishandle(app.UIAxes) && isequal(get(app.UIAxes,'type'),'axes');
end

% Clean up
clear Webcam;
release(video_Player);
release(point_Tracker);
release(face_Detector);

end

function camWarningMessageBox(msg)
    h = msgbox(msg, 'Warning', 'warn');
    set(h, 'position', [500, 500, 250, 75]);
        end

        % Button pushed function: Camera2Button
        function Camera2ButtonPushed(app, event)


webcam2 = webcam(1);
if ~exist('webcam2', 'file')
    msgbox('Error: Webcam not detected.', 'Error', 'error');
    return;
end
load net;
video_Player = vision.VideoPlayer();

face_Detector = vision.CascadeObjectDetector();
point_Tracker = vision.PointTracker('MaxBidirectionalError', 2);
is_tracking = false; % Initialize tracking

run_loop = true;
frame_Count = 0;

while isvalid(video_Player) && run_loop && frame_Count < 4000000
    video_Frame = snapshot(webcam2);
    gray_Frame = rgb2gray(video_Frame);
    frame_Count = frame_Count + 1;

    % Face detection
    bboxes = step(face_Detector, video_Frame);
    if ~isempty(bboxes)
        % Track the face if detected
        if ~is_tracking
            points = detectMinEigenFeatures(gray_Frame, 'ROI', bboxes(1, :));
            xy_Points = points.Location;
            number_of_Points = size(xy_Points, 1);

            if number_of_Points >= 10
                release(point_Tracker); % Release before re-initializing
                initialize(point_Tracker, xy_Points, gray_Frame);
                is_tracking = true;
                old_Points = xy_Points;
            end
        else
            [xy_Points, isFound] = step(point_Tracker, gray_Frame);
            new_Points = xy_Points(isFound, :);
            old_Points = old_Points(isFound, :);

            number_of_Points = size(new_Points, 1);

            if number_of_Points >= 10
                [xform, old_Points, new_Points] = estimateGeometricTransform(old_Points, new_Points, 'similarity', 'MaxDistance', 4);
                rectangle = bbox2points(bboxes(1, :));
                face_Polygon = reshape(rectangle', 1, []);
                video_Frame = insertShape(video_Frame, 'Polygon', face_Polygon, 'LineWidth', 3);
                video_Frame = insertMarker(video_Frame, new_Points, "+", 'Color', 'white');
                setPoints(point_Tracker, old_Points);
            else
                is_tracking = false;
            end
        end

        % Crop the face region and classify
        es = imcrop(video_Frame, bboxes(1, :));
        es = imresize(es, [227 227]);
        [label, scores] = classify(net, es);
        confidence = max(scores); % Maximum confidence score
        if confidence >= 0.7
            title_label = ['Tracking: ', char(label), ' (Confidence: ', num2str(confidence), ')'];
        else
            title_label = 'Outsider';
            msg = 'Warning: Outsider Detected!';
            showWarningMessageBox(msg);
        end
    else
        % Stop tracking if no face detected
        is_tracking = false;
        title_label = 'No Face Detected';
    end

    % Display the video frame with title
    imshow(video_Frame, 'Parent', app.UIAxes);
    title(app.UIAxes, title_label);
    drawnow;

    run_loop = isvalid(video_Player) && isvalid(app.UIFigure) && ishandle(app.UIAxes) && isequal(get(app.UIAxes,'type'),'axes');
end

% Clean up
clear Webcam;
release(video_Player);
release(point_Tracker);
release(face_Detector);

end

function cam2WarningMessageBox(msg)
    h = msgbox(msg, 'Warning', 'warn');
    set(h, 'position', [500, 500, 250, 75]);
        end

        % Button pushed function: Camera3Button
        function Camera3ButtonPushed(app, event)


webcam3 = webcam(1);
if ~exist('webcam3', 'file')
    msgbox('Error: Webcam not detected.', 'Error', 'error');
    return;
end
load net;
video_Player = vision.VideoPlayer();

face_Detector = vision.CascadeObjectDetector();
point_Tracker = vision.PointTracker('MaxBidirectionalError', 2);
is_tracking = false; % Initialize tracking
run_loop = true;
frame_Count = 0;

while isvalid(video_Player) && run_loop && frame_Count < 4000000
    video_Frame = snapshot(webcam3);
    gray_Frame = rgb2gray(video_Frame);
    frame_Count = frame_Count + 1;

    % Face detection
    bboxes = step(face_Detector, video_Frame);
    if ~isempty(bboxes)
        % Track the face if detected
        if ~is_tracking
            points = detectMinEigenFeatures(gray_Frame, 'ROI', bboxes(1, :));
            xy_Points = points.Location;
            number_of_Points = size(xy_Points, 1);

            if number_of_Points >= 10
                release(point_Tracker); 
                initialize(point_Tracker, xy_Points, gray_Frame);
                is_tracking = true;
                old_Points = xy_Points;
            end
        else
            [xy_Points, isFound] = step(point_Tracker, gray_Frame);
            new_Points = xy_Points(isFound, :);
            old_Points = old_Points(isFound, :);

            number_of_Points = size(new_Points, 1);

            if number_of_Points >= 10
                [xform, old_Points, new_Points] = estimateGeometricTransform(old_Points, new_Points, 'similarity', 'MaxDistance', 4);
                rectangle = bbox2points(bboxes(1, :));
                face_Polygon = reshape(rectangle', 1, []);
                video_Frame = insertShape(video_Frame, 'Polygon', face_Polygon, 'LineWidth', 3);
                video_Frame = insertMarker(video_Frame, new_Points, "+", 'Color', 'white');
                setPoints(point_Tracker, old_Points);
            else
                is_tracking = false;
            end
        end

        
        es = imcrop(video_Frame, bboxes(1, :));
        es = imresize(es, [227 227]);
        [label, scores] = classify(net, es);
        confidence = max(scores); %confidence score
        if confidence >= 0.7 
            title_label = ['Tracking: ', char(label), ' (Confidence: ', num2str(confidence), ')'];
        else
            title_label = 'Outsider';
            msg = 'Warning: Outsider Detected!';
            showWarningMessageBox(msg);
        end
    else
        
        is_tracking = false;
        title_label = 'No Face Detected';
    end

    
    imshow(video_Frame, 'Parent', app.UIAxes);
    title(app.UIAxes, title_label);
    drawnow;

    run_loop = isvalid(video_Player) && isvalid(app.UIFigure) && ishandle(app.UIAxes) && isequal(get(app.UIAxes,'type'),'axes');
end


clear Webcam;
release(video_Player);
release(point_Tracker);
release(face_Detector);

end

function cam3WarningMessageBox(msg)
    h = msgbox(msg, 'Warning', 'warn');
    set(h, 'position', [500, 500, 250, 75]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.DETECTIONSYSTEMLabel = uilabel(app.Panel_2);
            app.DETECTIONSYSTEMLabel.Text = 'DETECTION SYSTEM';
            app.Panel = uipanel(app.UIFigure);
            app.Camera2Button = uibutton(app.Panel, 'push');
            app.Camera2Button.ButtonPushedFcn = createCallbackFcn(app, @Camera2ButtonPushed, true);
            app.Camera2Button.Text = 'Camera 2';
            app.Camera2Button.Icon = fullfile(pathToMLAPP, 'security-camera.png');
            app.Camera3Button = uibutton(app.Panel, 'push');
            app.Camera3Button.ButtonPushedFcn = createCallbackFcn(app, @Camera3ButtonPushed, true);
            app.Camera3Button.Text = 'Camera 3';
            app.Camera3Button.Icon = fullfile(pathToMLAPP, 'security-camera.png');
            app.Camera1Button = uibutton(app.UIFigure, 'push');
            app.Camera1Button.ButtonPushedFcn = createCallbackFcn(app, @Camera1ButtonPushed, true);
            app.Camera1Button.Text = 'Camera 1';
            app.Camera1Button.Icon = fullfile(pathToMLAPP, 'security-camera.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outsider_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end